classdef HydroPol2D_Monitor_base < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        FloodAnalysisTab                matlab.ui.container.Tab
        gauges_list                     matlab.ui.control.DropDown
        ObservationGaugesDropDownLabel  matlab.ui.control.Label
        SystemOutput                    matlab.ui.control.TextArea
        SystemmessagesLabel             matlab.ui.control.Label
        iter                            matlab.ui.control.Label
        DateTimeTextArea                matlab.ui.control.TextArea
        LocaldatetimeLabel              matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        UIAxes_2                        matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
        UZGWTab                         matlab.ui.container.Tab
        Image_3                         matlab.ui.control.Image
        GWdepth                         matlab.ui.control.UIAxes
        UIAxes_5                        matlab.ui.control.UIAxes
        UIAxes_4                        matlab.ui.control.UIAxes
        UIAxes_3                        matlab.ui.control.UIAxes
        FluxesStoragesTab               matlab.ui.container.Tab
        Image_5                         matlab.ui.control.Image
        UIAxes_14                       matlab.ui.control.UIAxes
        UIAxes_13                       matlab.ui.control.UIAxes
        UIAxes_7                        matlab.ui.control.UIAxes
        UIAxes_6                        matlab.ui.control.UIAxes
        WaterQualityTab                 matlab.ui.container.Tab
        Image_6                         matlab.ui.control.Image
        UIAxes_11                       matlab.ui.control.UIAxes
        UIAxes_9                        matlab.ui.control.UIAxes
        UIAxes_8                        matlab.ui.control.UIAxes
        HumanRiskTab                    matlab.ui.container.Tab
        BoundaryConditionsTab           matlab.ui.container.Tab
        K_2TextArea                     matlab.ui.control.TextArea
        K_2TextAreaLabel                matlab.ui.control.Label
        h_0TextArea                     matlab.ui.control.TextArea
        h_0TextAreaLabel                matlab.ui.control.Label
        K_1TextArea                     matlab.ui.control.TextArea
        K_1Label_2                      matlab.ui.control.Label
        ApplyChangesButton_3            matlab.ui.control.Button
        ApplyChangesButton_2            matlab.ui.control.Button
        ModifyParametersLabel           matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        CurrentParametersLabel          matlab.ui.control.Label
        K_2EditField                    matlab.ui.control.NumericEditField
        K_2EditFieldLabel               matlab.ui.control.Label
        h_0EditField                    matlab.ui.control.NumericEditField
        h_0EditFieldLabel               matlab.ui.control.Label
        BoundaryConditionParametersLabel  matlab.ui.control.Label
        ApplyChangesButton              matlab.ui.control.Button
        K_1EditField                    matlab.ui.control.NumericEditField
        K_1Label                        matlab.ui.control.Label
        boundary_list                   matlab.ui.control.DropDown
        BoundaryConditionLabel          matlab.ui.control.Label
        Image_4                         matlab.ui.control.Image
        UIAxes_12                       matlab.ui.control.UIAxes
        AboutTab                        matlab.ui.container.Tab
        mateohs23gmailcomLabel          matlab.ui.control.Label
        MateoHernndezSnchezLabel        matlab.ui.control.Label
        luiscastillounahhnLabel         matlab.ui.control.Label
        marcusnobregaengcivilgmailcomLabel  matlab.ui.control.Label
        Image4                          matlab.ui.control.Image
        Image3                          matlab.ui.control.Image
        Image2                          matlab.ui.control.Image
        MarcusNbregaGomesJniorLabel     matlab.ui.control.Label
        LuisMiguelCastilloRpaloLabel    matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        DevelopersLabel                 matlab.ui.control.Label
        HydrodynamicPollutant2DmodelHydroPol2DLabel  matlab.ui.control.Label
        Image_2                         matlab.ui.control.Image
        UIAxes_10                       matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        Property % Description
    end
    
    % Add this
    properties (Access = public)
        AxesHandles = containers.Map;
        AxesHandles_boundary = containers.Map;
        CurrentGauge='';
        CurrentBoundary='';
        gauges_data;
        gauges_time;
        boundary_data;
        boundary_time;
        gauges_forecast;
        HADS_gauges;
        HADS_geoinfo;
        temp_gauge;
        temp_boundary_1;temp_boundary_2;temp_boundary_3;
        gauges;
        reservoirs;
        flags;
    end

    methods (Access = private)
        function app = myApp
            app.AxesHandles = containers.Map;
        end

        function [data] = fetchGaugeData(app, selectedGauge)
            % Implement the logic to retrieve data associated with the selected gauge
            % For simulated data:
            index = strcmp(app.gauges_list.Items, selectedGauge);
            data.x = table2array(app.gauges_time);
            data.y = table2array(app.gauges_data(:,index));

            if ~isempty(app.temp_gauge)
                delete(app.temp_gauge); % Delete the previous plot
            end
            % Add the new plot using mapshow
            app.temp_gauge = mapshow(app.UIAxes, ...
                mappoint(app.gauges.x_coord_gauges(index), app.gauges.y_coord_gauges(index)), ...
                'DisplayType', 'point', ...
                'Marker', 'o', ...
                'MarkerEdgeColor', 'r', ...
                'MarkerFaceColor', 'none', ...
                'MarkerSize', 11,'LineWidth', 3);
        end

        function [data] = fetchBoundaryData(app, selectedBoundary)
            % Implement the logic to retrieve data associated with the selected gauge
            % For simulated data:
            index = strcmp(app.boundary_list.Items, selectedBoundary);
            data.x = table2array(app.boundary_time);
            data.wse_1 = table2array(app.boundary_data.wse_1(:,index));
            data.d_1 = table2array(app.boundary_data.d_1(:,index));
            data.qin_1 = table2array(app.boundary_data.Qin_1(:,index));
            data.wse_2 = table2array(app.boundary_data.wse_2(:,index));
            data.d_2 = table2array(app.boundary_data.d_2(:,index));
            data.qin_2 = table2array(app.boundary_data.Qin_2(:,index));
            try
                data.wse_3 = table2array(app.boundary_data.wse_3(:,index));
                data.d_3 = table2array(app.boundary_data.d_3(:,index));
                data.qin_3 = table2array(app.boundary_data.Qin_3(:,index));
            catch
            end

            if ~isempty(app.temp_boundary_1)
                delete(app.temp_boundary_1); % Delete the previous plot
                delete(app.temp_boundary_2);
                try
                    delete(app.temp_boundary_3);
                catch
                end
            end

            % Add the new plot using mapshow
            app.temp_boundary_1 = mapshow(app.UIAxes_12, ...
                mappoint(app.reservoirs.x_us(index), app.reservoirs.y_us(index)), ...
                'DisplayType', 'point', ...
                'Marker', 'o', ...
                'MarkerEdgeColor', 'r', ...
                'MarkerFaceColor', 'none', ...
                'MarkerSize', 11,'LineWidth', 3);
            app.temp_boundary_2 = mapshow(app.UIAxes_12, ...
                mappoint(app.reservoirs.x_ds1(index), app.reservoirs.y_ds1(index)), ...
                'DisplayType', 'point', ...
                'Marker', 'o', ...
                'MarkerEdgeColor', 'r', ...
                'MarkerFaceColor', 'none', ...
                'MarkerSize', 11,'LineWidth', 3);
            try
                app.temp_boundary_3 = mapshow(app.UIAxes_12, ...
                    mappoint(app.reservoirs.x_ds2(index), app.reservoirs.y_ds2(index)), ...
                    'DisplayType', 'point', ...
                    'Marker', 'o', ...
                    'MarkerEdgeColor', 'r', ...
                    'MarkerFaceColor', 'none', ...
                    'MarkerSize', 11,'LineWidth', 3);
            catch
            end
            % to zoom the boundary condition
            if app.flags.flag_GPU == 1
                app.UIAxes_12.XLim = [gather(app.reservoirs.x_us(index))*1.01 gather(app.reservoirs.x_us(index))*0.99];
                app.UIAxes_12.YLim = [gather(app.reservoirs.y_us(index))*1.01 gather(app.reservoirs.y_us(index))*0.99];
            else
                app.UIAxes_12.XLim = [app.reservoirs.x_us(index)*1.01 app.reservoirs.x_us(index)*0.99];
                app.UIAxes_12.YLim = [app.reservoirs.y_us(index)*1.01 app.reservoirs.y_us(index)*0.99];
            end

            % Print the current reservoir parameters
            set(app.K_1TextArea,'Value', num2str(app.reservoirs.k1(app.boundary_list.ValueIndex)));
            set(app.h_0TextArea,'Value', num2str(app.reservoirs.h1(app.boundary_list.ValueIndex)));
            set(app.K_2TextArea,'Value', num2str(app.reservoirs.k2(app.boundary_list.ValueIndex)));
        end

        function updatePlot(app, tab, selectedGauge)
            gaugeName = selectedGauge;
        
            % Safely delete old axes
            if isKey(app.AxesHandles, app.CurrentGauge)
                try
                    delete(app.AxesHandles(app.CurrentGauge));
                catch deleteErr
                    warning("Failed to delete old axes for gauge '%s': %s", app.CurrentGauge, deleteErr.message);
                end
            end
        
            % Create new axes
            defaultPosition = [550 500 450 200];
            az = uiaxes(tab, 'Position', defaultPosition);
            app.AxesHandles(gaugeName) = az;
        
            % Fetch data
            data = fetchGaugeData(app, selectedGauge);
        
            % Plot
            cla(az)
            hold(az, 'on');
            plot(az, data.x, data.y, 'LineWidth', 1.5, 'DisplayName', 'Modeled');
            box(az, 'on');
            hold(az, 'off');
        
            % Decorate
            xlabel(az, 'Time');
            ylabel(az, 'Water Depth (m)');
            title(az, ['Monitor for ', gaugeName]);
            grid(az, 'on');
            az.FontName = 'Montserrat';
            az.FontSize = 16;
            az.YAxis.Exponent = 0;
            legend(az, 'Location', 'Best');
        
            % Update current
            app.CurrentGauge = gaugeName;
        end

        function updatePlot_boundary(app, tab, selectedBoundary)
           gaugeName = selectedBoundary;
           
           % Get stored handle or create new axes
           % Delete old plot~ 
           if app.AxesHandles_boundary.Count==0
           else
               delete(app.AxesHandles_boundary(app.CurrentBoundary));
           end

          % Create new figure with two subplots
          panel = uipanel(tab);
          panel.Position = [50 200 450 500];
          panel.BorderType = 'none';
          
          % Create top subplot
          ax1 = uiaxes(panel); ax1.Position = [0 230 430 230];

          % Create bottom subplot
          ax2 = uiaxes(panel); ax2.Position = [0 0 430 230];

          % Store handles
          app.AxesHandles_boundary(gaugeName) = panel;
          % app.AxesHandles_boundary.(gaugeName).ax1 = ax1;
          % app.AxesHandles_boundary.(gaugeName).ax2 = ax2;

          % az = uiaxes(tab,'Position',defaultPosition);
          % app.AxesHandles_boundary(gaugeName) = az;
          
          % Fetch data
          [data] = fetchBoundaryData(app, selectedBoundary);  
          
          % Plot 
          % cla(az)
          hold(ax1, 'on');
          % Plot upstream depths on the left y-axis
          yyaxis(ax1, 'left');
          plot(ax1, data.x, data.d_1, 'LineWidth', 1.5, 'DisplayName', 'Upstream Depths');
          plot(ax1, data.x, data.d_2, 'LineWidth', 1.5, 'DisplayName', 'Downstream Depths');
          try
            plot(ax1, data.x, data.d_3, 'LineWidth', 1, 'DisplayName', 'Spillway Water Depths');
          catch
          end
          ylabel(ax1, 'Water Depth (m)');
          title(ax1, strcat('Monitor for ',32, gaugeName));
          box(ax1, 'on'); grid(ax1, 'on');

          % Plot upstream WSE on the right y-axis
          yyaxis(ax1,'right');
          plot(ax1, data.x, data.wse_1, 'LineWidth', 1.5, 'DisplayName', 'Upstream WSE');
          plot(ax1, data.x, data.wse_2, 'LineWidth', 1.5, 'DisplayName', 'Downstream WSE');
          try
            plot(ax1, data.x, data.wse_3, 'LineWidth', 1.5, 'DisplayName', 'Spillway WSE');
          catch
          end
          ylabel(ax1,'Water Surface Elevation');
          % Add the legend
          legend(ax1, 'Location', 'Best');
          hold(ax1, 'off');

          hold(ax2, 'on');
          plot(ax2, data.x, data.qin_1, 'LineWidth', 1.5, 'DisplayName', 'Upstream Discharge');
          plot(ax2, data.x, data.qin_2, 'LineWidth', 1.5, 'DisplayName', 'Downstream Discharge');
          try
            plot(ax2, data.x, data.qin_3, 'LineWidth', 1.5, 'DisplayName', 'Spillway Discharge');   
          catch
          end
          ylabel(ax2, 'Streamflow (m3/s)');
          box(ax2, 'on'); grid(ax2, 'on');
          xlabel(ax2, 'Time');
          legend(ax2, 'Location', 'Best');
          hold(ax2, 'off');
          
          
          % Labels, title, etc
          
          set(panel,'FontName','Montserrat','FontSize',16)
          % az.YAxis.Exponent = 0;

          app.CurrentBoundary = gaugeName;
        end
    end

    methods
        function set.gauges_time(app, newGaugesData) % This function updates the plot when gagues info is retrieved
            app.gauges_time = newGaugesData;
            % Check if CurrentGauge is non-empty and AxesHandles contains it
            if ~isempty(app.CurrentGauge) && isKey(app.AxesHandles, app.CurrentGauge)
                currentTab = app.FloodAnalysisTab;
                updatePlot(app, currentTab, app.CurrentGauge);
            end
        end
    end

    methods
        function set.boundary_time(app, newBoundaryData) % This function updates the plot when Boundarys info is retrieved
            app.boundary_time = newBoundaryData;
            % Check if CurrentGauge is non-empty and AxesHandles contains it
            if ~isempty(app.CurrentBoundary) && isKey(app.AxesHandles_boundary, app.CurrentBoundary)
                currentTab = app.BoundaryConditionsTab;
                updatePlot_boundary(app, currentTab, app.CurrentBoundary);
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: gauges_list
        function gauges_listValueChanged2(app, event)
            selectedGauge = app.gauges_list.Value;
            currentTab = app.FloodAnalysisTab;
            updatePlot(app, currentTab, selectedGauge);
        end

        % Drop down opening function: boundary_list
        function boundary_listDropDownOpening(app, event)
            selectedBoundary = app.boundary_list.Value;
            currentTab = app.BoundaryConditionsTab;
            updatePlot_boundary(app, currentTab, selectedBoundary);
        end

        % Button pushed function: ApplyChangesButton
        function ApplyChangesButtonPushed(app, event)
            app.reservoirs.k1(app.boundary_list.ValueIndex) = app.K_1EditField.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes_10 = uiaxes(app.UIFigure);
            title(app.UIAxes_10, 'Pollutant Load (mg/sec)', 'Interpreter', 'latex')
            xlabel(app.UIAxes_10, 'Easting (m)', 'Interpreter', 'latex')
            ylabel(app.UIAxes_10, 'Northing (m)', 'Interpreter', 'latex')
            zlabel(app.UIAxes_10, 'Z', 'Interpreter', 'latex')
            app.TabGroup = uitabgroup(app.UIFigure);
            app.FloodAnalysisTab = uitab(app.TabGroup);
            app.FloodAnalysisTab.Title = 'Flood Analysis';
            app.UIAxes2 = uiaxes(app.FloodAnalysisTab);
            title(app.UIAxes2, 'Rainfall Intensity [mm/h]', 'Interpreter', 'latex')
            xlabel(app.UIAxes2, 'Easting (m)', 'Interpreter', 'latex')
            ylabel(app.UIAxes2, 'Northing (m)', 'Interpreter', 'latex')
            zlabel(app.UIAxes2, 'Z', 'Interpreter', 'latex')
            app.UIAxes = uiaxes(app.FloodAnalysisTab);
            title(app.UIAxes, 'Flood Depth [m]', 'Interpreter', 'latex')
            xlabel(app.UIAxes, 'Easting (m)', 'Interpreter', 'latex')
            ylabel(app.UIAxes, 'Northing (m)', 'Interpreter', 'latex')
            zlabel(app.UIAxes, 'Z', 'Interpreter', 'latex')
            app.UIAxes_2 = uiaxes(app.FloodAnalysisTab);
            title(app.UIAxes_2, 'Total Velocity [m/s]', 'Interpreter', 'latex')
            xlabel(app.UIAxes_2, 'Easting (m)', 'Interpreter', 'latex')
            ylabel(app.UIAxes_2, 'Northing (m)', 'Interpreter', 'latex')
            zlabel(app.UIAxes_2, 'Z', 'Interpreter', 'latex')
            app.Image = uiimage(app.FloodAnalysisTab);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'HydroPol2D_logo.png');
            app.LocaldatetimeLabel = uilabel(app.FloodAnalysisTab);
            app.LocaldatetimeLabel.Text = 'Time :';
            app.DateTimeTextArea = uitextarea(app.FloodAnalysisTab);
            app.iter = uilabel(app.FloodAnalysisTab);
            app.SystemmessagesLabel = uilabel(app.FloodAnalysisTab);
            app.SystemmessagesLabel.Text = 'System Output: ';
            app.SystemOutput = uitextarea(app.FloodAnalysisTab);
            app.ObservationGaugesDropDownLabel = uilabel(app.FloodAnalysisTab);
            app.ObservationGaugesDropDownLabel.Text = {'Observation'; 'Gauges'};
            app.gauges_list = uidropdown(app.FloodAnalysisTab);
            app.gauges_list.ValueChangedFcn = createCallbackFcn(app, @gauges_listValueChanged2, true);
            app.UZGWTab = uitab(app.TabGroup);
            app.UZGWTab.Title = 'UZ/GW';
            app.UIAxes_3 = uiaxes(app.UZGWTab);
            title(app.UIAxes_3, 'Infiltration Capacity [mm/h]', 'Interpreter', 'latex')
            xlabel(app.UIAxes_3, 'Easting (m)', 'Interpreter', 'latex')
            ylabel(app.UIAxes_3, 'Northing (m)', 'Interpreter', 'latex')
            zlabel(app.UIAxes_3, 'Z', 'Interpreter', 'latex')
            app.UIAxes_4 = uiaxes(app.UZGWTab);
            title(app.UIAxes_4, 'Unsaturated Zone Moisture [mm]', 'Interpreter', 'latex')
            xlabel(app.UIAxes_4, 'Easting (m)', 'Interpreter', 'latex')
            ylabel(app.UIAxes_4, 'Northing (m)', 'Interpreter', 'latex')
            zlabel(app.UIAxes_4, 'Z', 'Interpreter', 'latex')
            app.UIAxes_5 = uiaxes(app.UZGWTab);
            title(app.UIAxes_5, 'Infiltration Rate [mm/h]', 'Interpreter', 'latex')
            xlabel(app.UIAxes_5, 'Easting (m)', 'Interpreter', 'latex')
            ylabel(app.UIAxes_5, 'Northing (m)', 'Interpreter', 'latex')
            zlabel(app.UIAxes_5, 'Z', 'Interpreter', 'latex')
            app.GWdepth = uiaxes(app.UZGWTab);
            title(app.GWdepth, 'GW depth [m]', 'Interpreter', 'latex')
            xlabel(app.GWdepth, 'Easting (m)', 'Interpreter', 'latex')
            ylabel(app.GWdepth, 'Northing (m)', 'Interpreter', 'latex')
            zlabel(app.GWdepth, 'Z', 'Interpreter', 'latex')
            app.Image_3 = uiimage(app.UZGWTab);
            app.Image_3.ImageSource = fullfile(pathToMLAPP, 'HydroPol2D_logo.png');
            app.FluxesStoragesTab = uitab(app.TabGroup);
            app.FluxesStoragesTab.Title = 'Fluxes/Storages';
            app.UIAxes_6 = uiaxes(app.FluxesStoragesTab);
            title(app.UIAxes_6, ' Potential Evapotransmiration (mm/day)', 'Interpreter', 'latex')
            xlabel(app.UIAxes_6, 'Easting (m)', 'Interpreter', 'latex')
            ylabel(app.UIAxes_6, 'Northing (m)', 'Interpreter', 'latex')
            zlabel(app.UIAxes_6, 'Z', 'Interpreter', 'latex')
            app.UIAxes_7 = uiaxes(app.FluxesStoragesTab);
            title(app.UIAxes_7, 'Real Evapotransmiration [mm/day]', 'Interpreter', 'latex')
            xlabel(app.UIAxes_7, 'Easting (m)', 'Interpreter', 'latex')
            ylabel(app.UIAxes_7, 'Northing (m)', 'Interpreter', 'latex')
            zlabel(app.UIAxes_7, 'Z', 'Interpreter', 'latex')
            app.UIAxes_13 = uiaxes(app.FluxesStoragesTab);
            title(app.UIAxes_13, 'Canopy Storage [mm]', 'Interpreter', 'latex')
            xlabel(app.UIAxes_13, 'Easting (m)', 'Interpreter', 'latex')
            ylabel(app.UIAxes_13, 'Northing (m)', 'Interpreter', 'latex')
            zlabel(app.UIAxes_13, 'Z', 'Interpreter', 'latex')
            app.UIAxes_14 = uiaxes(app.FluxesStoragesTab);
            title(app.UIAxes_14, 'Snowpack [mm]', 'Interpreter', 'latex')
            xlabel(app.UIAxes_14, 'Easting (m)', 'Interpreter', 'latex')
            ylabel(app.UIAxes_14, 'Northing (m)', 'Interpreter', 'latex')
            zlabel(app.UIAxes_14, 'Z', 'Interpreter', 'latex')
            app.Image_5 = uiimage(app.FluxesStoragesTab);
            app.Image_5.ImageSource = fullfile(pathToMLAPP, 'HydroPol2D_logo.png');
            app.WaterQualityTab = uitab(app.TabGroup);
            app.WaterQualityTab.Title = 'Water Quality';
            app.UIAxes_8 = uiaxes(app.WaterQualityTab);
            title(app.UIAxes_8, 'Pollutant Concentration [mg/L]', 'Interpreter', 'latex')
            xlabel(app.UIAxes_8, 'Easting (m)', 'Interpreter', 'latex')
            ylabel(app.UIAxes_8, 'Northing (m)', 'Interpreter', 'latex')
            zlabel(app.UIAxes_8, 'Z', 'Interpreter', 'latex')
            app.UIAxes_9 = uiaxes(app.WaterQualityTab);
            title(app.UIAxes_9, 'Pollutant Load [mg/sec]', 'Interpreter', 'latex')
            xlabel(app.UIAxes_9, 'Easting (m)', 'Interpreter', 'latex')
            ylabel(app.UIAxes_9, 'Northing (m)', 'Interpreter', 'latex')
            zlabel(app.UIAxes_9, 'Z', 'Interpreter', 'latex')
            app.UIAxes_11 = uiaxes(app.WaterQualityTab);
            title(app.UIAxes_11, 'Buildup [g/m2]', 'Interpreter', 'latex')
            xlabel(app.UIAxes_11, 'Easting (m)', 'Interpreter', 'latex')
            ylabel(app.UIAxes_11, 'Northing (m)', 'Interpreter', 'latex')
            zlabel(app.UIAxes_11, 'Z', 'Interpreter', 'latex')
            app.Image_6 = uiimage(app.WaterQualityTab);
            app.Image_6.ImageSource = fullfile(pathToMLAPP, 'HydroPol2D_logo.png');
            app.HumanRiskTab = uitab(app.TabGroup);
            app.HumanRiskTab.Title = 'Human Risk';
            app.BoundaryConditionsTab = uitab(app.TabGroup);
            app.BoundaryConditionsTab.Title = 'Boundary Conditions';
            app.UIAxes_12 = uiaxes(app.BoundaryConditionsTab);
            title(app.UIAxes_12, 'Flood Depth [m]', 'Interpreter', 'latex')
            xlabel(app.UIAxes_12, 'Easting (m)', 'Interpreter', 'latex')
            ylabel(app.UIAxes_12, 'Northing (m)', 'Interpreter', 'latex')
            zlabel(app.UIAxes_12, 'Z', 'Interpreter', 'latex')
            app.Image_4 = uiimage(app.BoundaryConditionsTab);
            app.Image_4.ImageSource = fullfile(pathToMLAPP, 'HydroPol2D_logo.png');
            app.BoundaryConditionLabel = uilabel(app.BoundaryConditionsTab);
            app.BoundaryConditionLabel.Text = {'Boundary'; 'Condition'};
            app.boundary_list = uidropdown(app.BoundaryConditionsTab);
            app.boundary_list.DropDownOpeningFcn = createCallbackFcn(app, @boundary_listDropDownOpening, true);
            app.K_1Label = uilabel(app.BoundaryConditionsTab);
            app.K_1Label.Interpreter = 'latex';
            app.K_1Label.Text = 'K$_1$';
            app.K_1EditField = uieditfield(app.BoundaryConditionsTab, 'numeric');
            app.ApplyChangesButton = uibutton(app.BoundaryConditionsTab, 'push');
            app.ApplyChangesButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyChangesButtonPushed, true);
            app.ApplyChangesButton.Text = 'Apply Changes';
            app.BoundaryConditionParametersLabel = uilabel(app.BoundaryConditionsTab);
            app.BoundaryConditionParametersLabel.Text = 'Boundary Condition Parameters';
            app.h_0EditFieldLabel = uilabel(app.BoundaryConditionsTab);
            app.h_0EditFieldLabel.Interpreter = 'latex';
            app.h_0EditFieldLabel.Text = 'h$_0$';
            app.h_0EditField = uieditfield(app.BoundaryConditionsTab, 'numeric');
            app.K_2EditFieldLabel = uilabel(app.BoundaryConditionsTab);
            app.K_2EditFieldLabel.Interpreter = 'latex';
            app.K_2EditFieldLabel.Text = 'K$_2$';
            app.K_2EditField = uieditfield(app.BoundaryConditionsTab, 'numeric');
            app.CurrentParametersLabel = uilabel(app.BoundaryConditionsTab);
            app.CurrentParametersLabel.Text = 'Current Parameters';
            app.Label_2 = uilabel(app.BoundaryConditionsTab);
            app.Label_2.Text = '_____________________________________________';
            app.ModifyParametersLabel = uilabel(app.BoundaryConditionsTab);
            app.ModifyParametersLabel.Text = 'Modify Parameters';
            app.ApplyChangesButton_2 = uibutton(app.BoundaryConditionsTab, 'push');
            app.ApplyChangesButton_2.Text = 'Apply Changes';
            app.ApplyChangesButton_3 = uibutton(app.BoundaryConditionsTab, 'push');
            app.ApplyChangesButton_3.Text = 'Apply Changes';
            app.K_1Label_2 = uilabel(app.BoundaryConditionsTab);
            app.K_1Label_2.Interpreter = 'latex';
            app.K_1Label_2.Text = 'K$_1$';
            app.K_1TextArea = uitextarea(app.BoundaryConditionsTab);
            app.h_0TextAreaLabel = uilabel(app.BoundaryConditionsTab);
            app.h_0TextAreaLabel.Interpreter = 'latex';
            app.h_0TextAreaLabel.Text = 'h$_0$';
            app.h_0TextArea = uitextarea(app.BoundaryConditionsTab);
            app.K_2TextAreaLabel = uilabel(app.BoundaryConditionsTab);
            app.K_2TextAreaLabel.Interpreter = 'latex';
            app.K_2TextAreaLabel.Text = 'K$_2$';
            app.K_2TextArea = uitextarea(app.BoundaryConditionsTab);
            app.AboutTab = uitab(app.TabGroup);
            app.AboutTab.Title = 'About';
            app.Image_2 = uiimage(app.AboutTab);
            app.Image_2.ImageSource = fullfile(pathToMLAPP, 'HydroPol2D_logo.png');
            app.HydrodynamicPollutant2DmodelHydroPol2DLabel = uilabel(app.AboutTab);
            app.HydrodynamicPollutant2DmodelHydroPol2DLabel.Text = {'Hydrodynamic Pollutant 2D model'; '(HydroPol2D)'};
            app.DevelopersLabel = uilabel(app.AboutTab);
            app.DevelopersLabel.Text = 'Developers:';
            app.Label = uilabel(app.AboutTab);
            app.Label.Text = '_________________________________________________________________________________________________';
            app.LuisMiguelCastilloRpaloLabel = uilabel(app.AboutTab);
            app.LuisMiguelCastilloRpaloLabel.Text = 'Luis Miguel Castillo Rápalo';
            app.MarcusNbregaGomesJniorLabel = uilabel(app.AboutTab);
            app.MarcusNbregaGomesJniorLabel.Text = 'Marcus Nóbrega Gomes Júnior';
            app.Image2 = uiimage(app.AboutTab);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'eesc_usp.png');
            app.Image3 = uiimage(app.AboutTab);
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'shs.jpg');
            app.Image4 = uiimage(app.AboutTab);
            app.Image4.ImageSource = fullfile(pathToMLAPP, 'UTSA.png');
            app.marcusnobregaengcivilgmailcomLabel = uilabel(app.AboutTab);
            app.marcusnobregaengcivilgmailcomLabel.Text = 'marcusnobregaengcivil@gmail.com';
            app.luiscastillounahhnLabel = uilabel(app.AboutTab);
            app.luiscastillounahhnLabel.Text = 'luis.castillo@unah.hn';
            app.MateoHernndezSnchezLabel = uilabel(app.AboutTab);
            app.MateoHernndezSnchezLabel.Text = 'Mateo Hernández Sánchez';
            app.mateohs23gmailcomLabel = uilabel(app.AboutTab);
            app.mateohs23gmailcomLabel.Text = 'mateohs23@gmail.com';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HydroPol2D_Monitor_base

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end