classdef HydroPol2D_Monitor_forecast < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        RainfallWaterDepthsForecastTab  matlab.ui.container.Tab
        gauges_list                     matlab.ui.control.DropDown
        ObservationGaugesDropDownLabel  matlab.ui.control.Label
        SystemOutput                    matlab.ui.control.TextArea
        SystemmessagesLabel             matlab.ui.control.Label
        iter                            matlab.ui.control.Label
        DateTimeTextArea                matlab.ui.control.TextArea
        LocaldatetimeLabel              matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        UIAxes                          matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
        SoilPropertiesForecastTab       matlab.ui.container.Tab
        Image_3                         matlab.ui.control.Image
        UIAxes_3                        matlab.ui.control.UIAxes
        UIAxes_2                        matlab.ui.control.UIAxes
        AboutTab                        matlab.ui.container.Tab
        luiscastillounahhnLabel         matlab.ui.control.Label
        marcusnobregaengcivilgmailcomLabel  matlab.ui.control.Label
        Image4                          matlab.ui.control.Image
        Image3                          matlab.ui.control.Image
        Image2                          matlab.ui.control.Image
        ForecastsystemLabel             matlab.ui.control.Label
        MainmodelLabel                  matlab.ui.control.Label
        MarcusNbregaGomesJniorLabel     matlab.ui.control.Label
        LuisMiguelCastilloRpaloLabel    matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        DevelopersLabel                 matlab.ui.control.Label
        HydrodynamicPollutant2DmodelHydroPol2DLabel  matlab.ui.control.Label
        Image_2                         matlab.ui.control.Image
    end

    
    properties (Access = public)
        Property % Description
    end
    
    % Add this
    properties (Access = public)
        AxesHandles = containers.Map;
        CurrentGauge='';
        gauges_data;
        gauges_time;
        gauges_forecast;
        time_zone;
        HADS_gauges;
        HADS_geoinfo;
        temp_gauge;
    end

    methods (Access = private)
        function app = myApp
            app.AxesHandles = containers.Map;
        end

        function [data,data2,dataf] = fetchGaugeData(app, selectedGauge)
            % Implement the logic to retrieve data associated with the selected gauge
            % For simulated data:
            index = strcmp(app.gauges_list.Items, selectedGauge);
            data.x = table2array(app.gauges_time);
            data.y = table2array(app.gauges_data(:,index));
  
            % For simulated data:
            if app.time_zone > 0
                con_0 = app.gauges_forecast.code_simu == 24-app.time_zone;
            elseif app.time_zone < 0
                con_0 = app.gauges_forecast.code_simu == 0-app.time_zone;
            end
            con_1 = app.gauges_forecast.code_simu == 6-app.time_zone;
            con_2 = app.gauges_forecast.code_simu == 12-app.time_zone;
            con_3 = app.gauges_forecast.code_simu == 18-app.time_zone;
            % Extracting values according index from forecast
            dataf.c0y = table2array(app.gauges_forecast(con_0,index));
            dataf.c1y = table2array(app.gauges_forecast(con_1,index));
            dataf.c2y = table2array(app.gauges_forecast(con_2,index));
            dataf.c3y = table2array(app.gauges_forecast(con_3,index));
            % Extracting time according index from forecast
            dataf.c0x = table2array(app.gauges_forecast(con_0,'Time'));
            dataf.c1x = table2array(app.gauges_forecast(con_1,'Time'));
            dataf.c2x = table2array(app.gauges_forecast(con_2,'Time'));
            dataf.c3x = table2array(app.gauges_forecast(con_3,'Time'));

            % For observed data:
            index = find(ismember(app.HADS_gauges.Properties.VariableNames,selectedGauge));
            data2.x = datetime.empty; % Initialize as empty datetime array
            data2.y = double.empty;   % Initialize as empty double array
        
            if sum(ismember(app.HADS_gauges.Properties.VariableNames, selectedGauge))
                data2.x = table2array(app.HADS_gauges(:, 'Time'));
                data2.y = table2array(app.HADS_gauges(:, index));
            end
            % Plotting the selected gauge
            % Initialize an empty character array
            % Initialize a cell array for storing character vectors
            charArray = cell(1, numel(app.HADS_geoinfo.labels_observed_string));

            % Convert each cell element to a character vector
            for i = 1:numel(app.HADS_geoinfo.labels_observed_string)
                charArray{i} = char(app.HADS_geoinfo.labels_observed_string{i});
            end
            
            % Now you can use ismember with the character array
            index = find(ismember(charArray, selectedGauge));
            if ~isempty(app.temp_gauge)
                delete(app.temp_gauge); % Delete the previous plot
            end
            % Add the new plot using mapshow
            app.temp_gauge = mapshow(app.UIAxes, mappoint(app.HADS_geoinfo.x_coord_gauges(index), app.HADS_geoinfo.y_coord_gauges(index)), ...
                'DisplayType', 'point', ...
                'Marker', 'o', ...
                'MarkerEdgeColor', 'r', ...
                'MarkerFaceColor', 'none', ...
                'MarkerSize', 11,'LineWidth', 3);
            
        end

        function updatePlot(app, tab, selectedGauge)
           gaugeName = selectedGauge;
           
           % Get stored handle or create new axes
           % Delete old plot 
           if ~isempty(app.AxesHandles)
               delete(app.AxesHandles(app.CurrentGauge));
           end

          % Create new axes
          defaultPosition = [50 100 550 300];
          ax = uiaxes(tab,'Position',defaultPosition);
          app.AxesHandles(gaugeName) = ax;
        
          % Fetch data
          [data,data2,dataf] = fetchGaugeData(app, selectedGauge);  
          
        
          % Plot 
          cla(ax)
          hold(ax, 'on');
          scatter(ax, data2.x, data2.y, 16, 'ko','DisplayName', 'Observed data');
          plot(ax, data.x, data.y,'bo-', 'MarkerSize', 8, 'LineWidth', 1.5,'DisplayName', 'Simulated data');
          
          % forecast plotting
          if app.time_zone > 0
              time_update_0 = 24-app.time_zone;
          else app.time_zone < 0
              time_update_0 = 0-app.time_zone;
          end
          time_update_1 = 6-app.time_zone;
          time_update_2 = 12-app.time_zone;
          time_update_3 = 18-app.time_zone;
          
          scatter(ax, dataf.c0x, dataf.c0y, 16, 'o', 'DisplayName', strcat('Forecast from->',num2str(time_update_0),' hours update'), 'MarkerFaceColor',"#D95319",'MarkerFaceAlpha', 0.6);
          scatter(ax, dataf.c1x, dataf.c1y, 16, 'o', 'DisplayName', strcat('Forecast from->',num2str(time_update_1),' hours update'), 'MarkerFaceColor',"#7E2F8E",'MarkerFaceAlpha', 0.6);
          scatter(ax, dataf.c2x, dataf.c2y, 16, 'o', 'DisplayName', strcat('Forecast from->',num2str(time_update_2),' hours update'), 'MarkerFaceColor',"#77AC30",'MarkerFaceAlpha', 0.6);
          scatter(ax, dataf.c3x, dataf.c3y, 16, 'o', 'DisplayName', strcat('Forecast from->',num2str(time_update_3),' hours update'), 'MarkerFaceColor',"#EDB120",'MarkerFaceAlpha', 0.6);
          hold(ax, 'off');

          % Labels, title, etc
          xlabel(ax, 'Time');
          ylabel(ax, 'Water levels (m)'); 
          title(ax, strcat('Monitor for - ', gaugeName));
          grid(ax, 'on');
          set(ax,'FontName','Garamond','FontSize',16)
          ax.YAxis.Exponent = 0;
          % Add the legend
          legend(ax, 'Location', 'Best');
          app.CurrentGauge = gaugeName;
        end
    end

    methods
        function set.gauges_time(app, newGaugesData) % This function updates the plot when gagues info is retrieved
            app.gauges_time = newGaugesData;
            % Check if CurrentGauge is non-empty and AxesHandles contains it
            if ~isempty(app.CurrentGauge) && isKey(app.AxesHandles, app.CurrentGauge)
                currentTab = app.RainfallWaterDepthsForecastTab;
                updatePlot(app, currentTab, app.CurrentGauge);
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: gauges_list
        function gauges_listValueChanged2(app, event)
            selectedGauge = app.gauges_list.Value;
            currentTab = app.RainfallWaterDepthsForecastTab;
            updatePlot(app, currentTab, selectedGauge);

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.RainfallWaterDepthsForecastTab = uitab(app.TabGroup);
            app.RainfallWaterDepthsForecastTab.Title = 'Rainfall & Water Depths (Forecast)';
            app.UIAxes2 = uiaxes(app.RainfallWaterDepthsForecastTab);
            title(app.UIAxes2, 'Rainfall (mm/h)')
            xlabel(app.UIAxes2, 'Easting (m)')
            ylabel(app.UIAxes2, 'Northing (m)')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes = uiaxes(app.RainfallWaterDepthsForecastTab);
            title(app.UIAxes, 'Water depths (m)')
            xlabel(app.UIAxes, 'Easting (m)')
            ylabel(app.UIAxes, 'Northing (m)')
            zlabel(app.UIAxes, 'Z')
            app.Image = uiimage(app.RainfallWaterDepthsForecastTab);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'HydroPol2D_logo.png');
            app.LocaldatetimeLabel = uilabel(app.RainfallWaterDepthsForecastTab);
            app.LocaldatetimeLabel.Text = 'Local date & time :';
            app.DateTimeTextArea = uitextarea(app.RainfallWaterDepthsForecastTab);
            app.iter = uilabel(app.RainfallWaterDepthsForecastTab);
            app.SystemmessagesLabel = uilabel(app.RainfallWaterDepthsForecastTab);
            app.SystemmessagesLabel.Text = 'System Output: ';
            app.SystemOutput = uitextarea(app.RainfallWaterDepthsForecastTab);
            app.ObservationGaugesDropDownLabel = uilabel(app.RainfallWaterDepthsForecastTab);
            app.ObservationGaugesDropDownLabel.Text = {'Observation'; 'Gauges'};
            app.gauges_list = uidropdown(app.RainfallWaterDepthsForecastTab);
            app.gauges_list.ValueChangedFcn = createCallbackFcn(app, @gauges_listValueChanged2, true);
            app.SoilPropertiesForecastTab = uitab(app.TabGroup);
            app.SoilPropertiesForecastTab.Title = 'Soil Properties (Forecast)';
            app.UIAxes_2 = uiaxes(app.SoilPropertiesForecastTab);
            title(app.UIAxes_2, 'Flow Velocity (m/s)')
            xlabel(app.UIAxes_2, 'Easting (m)')
            ylabel(app.UIAxes_2, 'Northing (m)')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_3 = uiaxes(app.SoilPropertiesForecastTab);
            title(app.UIAxes_3, 'Soil Moisture (mm)')
            xlabel(app.UIAxes_3, 'Easting (m)')
            ylabel(app.UIAxes_3, 'Northing (m)')
            zlabel(app.UIAxes_3, 'Z')
            app.Image_3 = uiimage(app.SoilPropertiesForecastTab);
            app.Image_3.ImageSource = fullfile(pathToMLAPP, 'HydroPol2D_logo.png');
            app.AboutTab = uitab(app.TabGroup);
            app.AboutTab.Title = 'About';
            app.Image_2 = uiimage(app.AboutTab);
            app.Image_2.ImageSource = fullfile(pathToMLAPP, 'HydroPol2D_logo.png');
            app.HydrodynamicPollutant2DmodelHydroPol2DLabel = uilabel(app.AboutTab);
            app.HydrodynamicPollutant2DmodelHydroPol2DLabel.Text = {'Hydrodynamic Pollutant 2D model'; '(HydroPol2D)'};
            app.DevelopersLabel = uilabel(app.AboutTab);
            app.DevelopersLabel.Text = 'Developers:';
            app.Label = uilabel(app.AboutTab);
            app.Label.Text = '_____________________________________________________________________________________________________________________________________________________________';
            app.LuisMiguelCastilloRpaloLabel = uilabel(app.AboutTab);
            app.LuisMiguelCastilloRpaloLabel.Text = 'Luis Miguel Castillo Rápalo';
            app.MarcusNbregaGomesJniorLabel = uilabel(app.AboutTab);
            app.MarcusNbregaGomesJniorLabel.Text = 'Marcus Nóbrega Gomes Júnior';
            app.MainmodelLabel = uilabel(app.AboutTab);
            app.MainmodelLabel.Text = 'Main model';
            app.ForecastsystemLabel = uilabel(app.AboutTab);
            app.ForecastsystemLabel.Text = 'Forecast system';
            app.Image2 = uiimage(app.AboutTab);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'eesc_usp.png');
            app.Image3 = uiimage(app.AboutTab);
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'shs.jpg');
            app.Image4 = uiimage(app.AboutTab);
            app.Image4.ImageSource = fullfile(pathToMLAPP, 'UTSA.png');
            app.marcusnobregaengcivilgmailcomLabel = uilabel(app.AboutTab);
            app.marcusnobregaengcivilgmailcomLabel.Text = 'marcusnobregaengcivil@gmail.com';
            app.luiscastillounahhnLabel = uilabel(app.AboutTab);
            app.luiscastillounahhnLabel.Text = 'luis.castillo@unah.hn';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HydroPol2D_Monitor_forecast

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end